classdef EasyModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyModelByGavin20200913UIFigure  matlab.ui.Figure
        SignalsProcessPanel        matlab.ui.container.Panel
        AllResolveButton           matlab.ui.control.Button
        OutResolveButton           matlab.ui.control.Button
        DelResolveButton           matlab.ui.control.Button
        AlignPanel                 matlab.ui.container.Panel
        InportLeftAlignButton      matlab.ui.control.Button
        InportRightAlignButton     matlab.ui.control.Button
        OutportLeftAlignButton     matlab.ui.control.Button
        OutportRightAlignButton_2  matlab.ui.control.Button
        SizeBusPanel               matlab.ui.container.Panel
        ConstantSizeButton         matlab.ui.control.Button
        BusSelectorButton          matlab.ui.control.Button
        BusCreatorButton           matlab.ui.control.Button
        GenPortsButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        num = 5; % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             s = settings;
% s.matlab.appdesigner = get(h,'JavaFrame');
% drawnow
% s.matlab.appdesigner.jFrame.fHG2Client.getWindow.setAlwaysOnTop(true);
        end

        % Button pushed function: AllResolveButton
        function AllResolveButtonPushed(app, event)
            AT_ResolveSignal;
        end

        % Callback function
        function BooleanButtonPushed(app, event)
            OrdIn_flg;
        end

        % Button pushed function: ConstantSizeButton
        function ConstantSizeButtonPushed(app, event)
            CostantSize;
        end

        % Button pushed function: InportLeftAlignButton
        function InportLeftAlignButtonPushed(app, event)
            InportsLeftAlign;
        end

        % Button pushed function: InportRightAlignButton
        function InportRightAlignButtonPushed(app, event)
            InportsRightAlign;
        end

        % Button pushed function: OutportLeftAlignButton
        function OutportLeftAlignButtonPushed(app, event)
            OutportsLeftAlign;
        end

        % Button pushed function: OutportRightAlignButton_2
        function OutportRightAlignButton_2Pushed(app, event)
            OutportsRightAlign;
        end

        % Button pushed function: BusSelectorButton
        function BusSelectorButtonPushed(app, event)
%             assignin('base','num',app.num);
            BusSelector;
        end

        % Button pushed function: BusCreatorButton
        function BusCreatorButtonPushed(app, event)
            BusCreator;
        end

        % Callback function
        function SingleButtonPushed(app, event)
            OrdIn_float;
        end

        % Callback function
        function Uint8EnumButtonPushed(app, event)
            OrdIn_enum8;
        end

        % Callback function
        function Uint16EnumButtonPushed(app, event)
            OrdIn_enum16;
        end

        % Callback function
        function BooleanButton_2Pushed(app, event)
            OrdOut_flg;
        end

        % Callback function
        function SingleButton_2Pushed(app, event)
            OrdOut_float;
        end

        % Callback function
        function Uint8EnumButton_2Pushed(app, event)
            OrdOut_enum8;
        end

        % Callback function
        function Uint16EnumButton_2Pushed(app, event)
            OrdOut_enum16;
        end

        % Button pushed function: OutResolveButton
        function OutResolveButtonPushed(app, event)
            OutResolve;
        end

        % Callback function
        function RenameResolveButtonPushed(app, event)
            RenameResolve;
        end

        % Button pushed function: GenPortsButton
        function GenPortsButtonPushed(app, event)
            Gen_Ports;
        end

        % Button pushed function: DelResolveButton
        function DelResolveButtonPushed(app, event)
            AT_DelResolve;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyModelByGavin20200913UIFigure = uifigure;
            app.EasyModelByGavin20200913UIFigure.Name = 'EasyModel                                                   By:Gavin 2020.09.13';
            app.SignalsProcessPanel = uipanel(app.EasyModelByGavin20200913UIFigure);
            app.SignalsProcessPanel.Title = 'SignalsProcess';
            app.AllResolveButton = uibutton(app.SignalsProcessPanel, 'push');
            app.AllResolveButton.ButtonPushedFcn = createCallbackFcn(app, @AllResolveButtonPushed, true);
            app.AllResolveButton.Text = 'AllResolve';
            app.OutResolveButton = uibutton(app.SignalsProcessPanel, 'push');
            app.OutResolveButton.ButtonPushedFcn = createCallbackFcn(app, @OutResolveButtonPushed, true);
            app.OutResolveButton.Text = 'OutResolve';
            app.DelResolveButton = uibutton(app.SignalsProcessPanel, 'push');
            app.DelResolveButton.ButtonPushedFcn = createCallbackFcn(app, @DelResolveButtonPushed, true);
            app.DelResolveButton.Text = 'DelResolve';
            app.AlignPanel = uipanel(app.EasyModelByGavin20200913UIFigure);
            app.AlignPanel.Title = 'Align';
            app.InportLeftAlignButton = uibutton(app.AlignPanel, 'push');
            app.InportLeftAlignButton.ButtonPushedFcn = createCallbackFcn(app, @InportLeftAlignButtonPushed, true);
            app.InportLeftAlignButton.Text = 'InportLeftAlign';
            app.InportRightAlignButton = uibutton(app.AlignPanel, 'push');
            app.InportRightAlignButton.ButtonPushedFcn = createCallbackFcn(app, @InportRightAlignButtonPushed, true);
            app.InportRightAlignButton.Text = 'InportRightAlign';
            app.OutportLeftAlignButton = uibutton(app.AlignPanel, 'push');
            app.OutportLeftAlignButton.ButtonPushedFcn = createCallbackFcn(app, @OutportLeftAlignButtonPushed, true);
            app.OutportLeftAlignButton.Text = 'OutportLeftAlign';
            app.OutportRightAlignButton_2 = uibutton(app.AlignPanel, 'push');
            app.OutportRightAlignButton_2.ButtonPushedFcn = createCallbackFcn(app, @OutportRightAlignButton_2Pushed, true);
            app.OutportRightAlignButton_2.Text = 'OutportRightAlign';
            app.SizeBusPanel = uipanel(app.EasyModelByGavin20200913UIFigure);
            app.SizeBusPanel.Title = 'Size&Bus';
            app.ConstantSizeButton = uibutton(app.SizeBusPanel, 'push');
            app.ConstantSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantSizeButtonPushed, true);
            app.ConstantSizeButton.Text = 'ConstantSize';
            app.BusSelectorButton = uibutton(app.SizeBusPanel, 'push');
            app.BusSelectorButton.ButtonPushedFcn = createCallbackFcn(app, @BusSelectorButtonPushed, true);
            app.BusSelectorButton.Text = 'BusSelector';
            app.BusCreatorButton = uibutton(app.SizeBusPanel, 'push');
            app.BusCreatorButton.ButtonPushedFcn = createCallbackFcn(app, @BusCreatorButtonPushed, true);
            app.BusCreatorButton.Text = 'BusCreator';
            app.GenPortsButton = uibutton(app.SizeBusPanel, 'push');
            app.GenPortsButton.ButtonPushedFcn = createCallbackFcn(app, @GenPortsButtonPushed, true);
            app.GenPortsButton.Text = 'GenPorts';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EasyModel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EasyModelByGavin20200913UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyModelByGavin20200913UIFigure)
        end
    end
end