classdef MeasureTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActualDataMeasureToolLabel      matlab.ui.control.Label
        ScopeZonePanel                  matlab.ui.container.Panel
        Sharp                           matlab.ui.control.UIAxes
        SharOn_Off                      matlab.ui.control.StateButton
        Sharp_Comp                      matlab.ui.control.StateButton
        RawValueEditFieldLabel          matlab.ui.control.Label
        RawValueEditField               matlab.ui.control.NumericEditField
        RawValueEditField_2             matlab.ui.control.NumericEditField
        RawValueEditFieldLabel_2        matlab.ui.control.Label
        ComandZonePanel                 matlab.ui.container.Panel
        StartMeasure                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        COM                             matlab.ui.control.DropDown
        SampSel                         matlab.ui.control.DropDown
        basetime                        matlab.ui.control.NumericEditField
        SaveButton                      matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        CalibrationPanel                matlab.ui.container.Panel
        stFilterConstantEditFieldLabel  matlab.ui.control.Label
        stFilterConstant                matlab.ui.control.EditField
    end

    
    properties (Access = public)
        x=0;
        y=0;
        y1=0;
        t=0;
        PlotLine;
        PlotLine1;
        s;
        data_pre;
        data_pre1;
        comp_sharp;
        data;
    end
    
    methods (Access = public)
        
        function results = ReceiveCallback(app,~,~)   %function start
            a = fscanf(app.s);       % 接收数据并显示（无分号）
            app.data = str2num(a);
            if isempty(app.data);%串口发送不稳定，导致data是空数据，如果为空，则与上一时刻保持一样  %if 1 start1
                app.data =app.data_pre;
            else
                if  app.SharOn_Off.Value&&app.Sharp_Comp.Value;   %if 2 start
                    app.data_pre = app.data(1,1);
                    app.data_pre1 = app.data(1,2);
                    app.RawValueEditField.Enable = 1;
                    app.RawValueEditField_2.Enable = 1;
                    app.RawValueEditField.Value = app.data(1,1);
                    app.RawValueEditField_2.Value = app.data(1,2);
                    app.x = [app.x app.t];
                    app.y = [app.y app.data(1,1)];
                    app.y1 = [app.y1 app.data(1,2)];
                    %               set(app.PlotLine, 'XData',app.x,'YData',app.y(1,:));
                    %               hold on
                    %               set(app.PlotLine, 'XData',app.x,'YData',app.y1(1,:));
                    plot(app.Sharp,app.x,app.y,'-.r*',app.x,app.y1,'-.b*');
                    switch app.SampSel.Value
                        case '200ms'
                            app.Sharp.XLim = [app.t-5 app.t+1];
                            step = app.basetime.Value;
                        case '100ms'
                            app.Sharp.XLim = [app.t-2 app.t+0.5];
                            step = app.basetime.Value;
                        case '50ms'
                            app.Sharp.XLim = [app.t-1 app.t+0.2];
                            step = app.basetime.Value;
                        case '20ms'
                            app.Sharp.XLim = [app.t-1 app.t+0.1];
                            step = app.basetime.Value;
                        case '10ms'
                            app.Sharp.XLim = [app.t-0.4 app.t+0.01];
                            step = app.basetime.Value;
                    end
                    if length(app.y)>20;
                        y_max = max(app.y(1,(end-20):end))+0.5;
                        y_max1 = max(app.y1(1,(end-20):end))+0.5;
                        if y_max>=y_max1;
                            app.Sharp.YLim = [0 y_max];
                        else
                            app.Sharp.YLim = [0 y_max1];
                        end
                    else
                        y_max = max(app.y(1,:))+0.5;
                        y_max1 = max(app.y1(1,:))+0.5;
                        if y_max>=y_max1;
                            app.Sharp.YLim = [0 y_max];
                        else
                            app.Sharp.YLim = [0 y_max1];
                        end
                    end
                    %                     if app.SaveButton.Value
                    %                         app.SaveButton.BackgroundColor = [0.00,1.00,0.00];
                    %                         A = [app.t; app.y;app.y1];
                    %                         fileID = fopen('exp.txt','w');
                    %                         fprintf(fileID,'%6s %12s %12s\n','t','Raw','Filter');
                    %                         fprintf(fileID,'%6.2f %12.8f %12.8f\n',A);
                    %                         fclose(fileID);
                    %                     else
                    %                         app.SaveButton.BackgroundColor = [0.94,0.94,0.94];
                    %                     end
                else if app.SharOn_Off.Value&&~app.Sharp_Comp.Value; %if 3 start
                        app.data_pre = app.data(1,1);
                        app.data_pre1 = 0;
                        app.RawValueEditField.Enable = 1;
                        app.RawValueEditField.Value = app.data(1,1);
                        app.RawValueEditField_2.Enable = 0;
                        app.x = [app.x app.t];
                        app.y = [app.y app.data(1,1)];
                        app.y1 = [app.y1 0];
                        %               set(app.PlotLine, 'XData',app.x,'YData',app.y(1,:));
                        %               hold on
                        %               set(app.PlotLine, 'XData',app.x,'YData',app.y1(1,:));
                        plot(app.Sharp,app.x,app.y,'-.r*');
                        switch app.SampSel.Value
                            case '200ms'
                                app.Sharp.XLim = [app.t-5 app.t+1];
                                step = app.basetime.Value;
                            case '100ms'
                                app.Sharp.XLim = [app.t-2 app.t+0.5];
                                step = app.basetime.Value;
                            case '50ms'
                                app.Sharp.XLim = [app.t-1 app.t+0.2];
                                step = app.basetime.Value;
                            case '20ms'
                                app.Sharp.XLim = [app.t-1 app.t+0.1];
                                step = app.basetime.Value;
                            case '10ms'
                                app.Sharp.XLim = [app.t-0.4 app.t+0.01];
                                step = app.basetime.Value;
                        end
                        if length(app.y)>20;
                            y_max = max(app.y(1,(end-20):end))+0.5;
                            app.Sharp.YLim = [0 y_max];
                        else
                            y_max = max(app.y(1,:))+0.5;
                            app.Sharp.YLim = [0 y_max];
                        end
                    else ~app.SharOn_Off.Value;
                        app.data_pre = app.data(1,1);
                        app.data_pre1 = 0;
                        app.RawValueEditField.Enable = 0;
                        app.RawValueEditField_2.Enable = 0;
                        app.x = [app.x app.t];
                        app.y = [app.y app.data(1,1)];
                        app.y1 = [app.y1 0];
                        %               set(app.PlotLine, 'XData',app.x,'YData',app.y(1,:));
                        %               hold on
                        %               set(app.PlotLine, 'XData',app.x,'YData',app.y1(1,:));
                        plot(app.Sharp,0,0,'-.r*');
                        switch app.SampSel.Value
                            case '200ms'
                                app.Sharp.XLim = [-5 1];
                                step = app.basetime.Value;
                            case '100ms'
                                app.Sharp.XLim = [-2 0.5];
                                step = app.basetime.Value;
                            case '50ms'
                                app.Sharp.XLim = [-1 0.2];
                                step = app.basetime.Value;
                            case '20ms'
                                app.Sharp.XLim = [-1 0.1];
                                step = app.basetime.Value;
                            case '10ms'
                                app.Sharp.XLim = [-0.4 0.01];
                                step = app.basetime.Value;
                        end
                        app.Sharp.YLim = [0 5];
                    end %if 3 end
                end   %if 2 end1
                app.t = app.t+app.basetime.Value;
                pause(app.basetime.Value);
            end %if 1 end
        end  %function end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Sharp.XLim = [app.t-5 app.t+1]; %显示精度 最小0.2 1s
            %app.Sharp.XLim = [app.t-2 app.t+0.5];%显示精度 最小0.1采样点 0.5s
            %app.Sharp.XLim = [app.t-1 app.t+0.2];%显示精度 最小0.05采样点 0.2s
            %app.Sharp.XLim = [app.t-1 app.t+0.1];%显示精度 最小0.02采样点 0.1s
            %app.Sharp.XLim = [app.t-0.4 app.t+0.01];%显示精度 最小0.01采样点 0.05s
            %app.Sharp.XLim = [app.t-0.2 app.t+0.01];%显示精度 最小0.005采样点 0.02s
            app.Sharp.YLim = [0 5];
            app.PlotLine = plot(app.Sharp,0,0,'-.r*');
            app.StartMeasure.BackgroundColor = [0.94,0.94,0.94];
            app.ResetButton.BackgroundColor = [0.94,0.94,0.94];
            Ports = instrhwinfo('serial');
            app.COM.Items=flipud(Ports.SerialPorts);%COM1一般为电脑自己的串口，这里把串口数从大到小排列赋值给COM
            %采样精度选择
            app.SampSel.Items = {'200ms';'100ms';'50ms';'20ms';'10ms'};%默认200ms
            switch app.SampSel.Value
                case '200ms'
                    app.basetime.Value = 0.2;
                case '100ms'
                    app.basetime.Value = 0.1;
                case '50ms'
                    app.basetime.Value = 0.05;
                case '20ms'
                    app.basetime.Value = 0.02;
                case '10ms'
                    app.basetime.Value = 0.01;
            end
            app.RawValueEditField.Enable = 0;
            app.RawValueEditField_2.Enable = 0;
        end

        % Button pushed function: StartMeasure
        function StartMeasureButtonPushed(app, event)
            delete(instrfindall)      % 关闭串口，此句十分重要，下篇再详细解释
            app.s = serial(app.COM.Value);       %使用默认设置创建串口s
            fopen(app.s);             %打开串口
            set(app.s,'BytesAvailableFcnMode','Terminator','BaudRate',115200); %设置中断触发方式
            set(app.s,'Terminator','LF');
            app.s.BytesAvailableFcn =@app.ReceiveCallback;       % 定义中断响应函数对象
            app.StartMeasure.BackgroundColor = [0.00,1.00,0.00];
            app.ResetButton.BackgroundColor = [0.94,0.94,0.94];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(instrfindall)      % 关闭串口，此句十分重要，下篇再详细解释
            clc
            clear all
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(instrfindall);
            app.ResetButton.BackgroundColor = [0.00,1.00,0.00];
            app.StartMeasure.BackgroundColor = [0.94,0.94,0.94];
        end

        % Value changed function: SharOn_Off
        function SharOn_OffValueChanged(app, event)
            value = app.SharOn_Off.Value;
            if value == 1
                app.SharOn_Off.Text = 'ON';
                app.SharOn_Off.BackgroundColor = [0.00,1.00,0.00];
            else
                app.SharOn_Off.Text = 'OFF';
                app.SharOn_Off.BackgroundColor = [0.94,0.94,0.94];
                app.Sharp_Comp.Value = 0;
                app.Sharp_Comp.BackgroundColor = [0.94,0.94,0.94];
            end
        end

        % Callback function
        function UltraOn_OffValueChanged(app, event)
            value = app.UltraOn_Off.Value;
            if value == 1
                app.UltraOn_Off.Text = 'ON';
                app.UltraOn_Off.BackgroundColor = [0.00,1.00,0.00];
            else
                app.UltraOn_Off.Text = 'OFF';
                app.UltraOn_Off.BackgroundColor = [0.94,0.94,0.94];
            end
        end

        % Value changed function: Sharp_Comp
        function Sharp_CompValueChanged(app, event)
            value = app.Sharp_Comp.Value;
            if value&&app.SharOn_Off.Value
                app.Sharp_Comp.BackgroundColor = [0.00,1.00,0.00];
                ReceiveCallback(app);
            else
                app.Sharp_Comp.BackgroundColor = [0.94,0.94,0.94];
            end
        end

        % Callback function
        function UltraCompValueChanged(app, event)
            value = app.UltraComp.Value;
            if value&&app.UltraOn_Off.Value
                app.UltraComp.BackgroundColor = [0.00,1.00,0.00];
            else
                app.UltraComp.BackgroundColor = [0.94,0.94,0.94];
            end
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            if app.SaveButton.Value
                app.SaveButton.BackgroundColor = [0.00,1.00,0.00];
                if app.Sharp_Comp.Value
                    %存入txt
                    A = [app.x; app.y;app.y1];
                    fileID = fopen([app.EditField.Value,'.txt'],'w');
                    fprintf(fileID,'%6s %12s %12s\n','t','data','data1');
                    fprintf(fileID,'%6.2f %12.8f %12.8f\n',A);
                    fclose(fileID);
                    %存入Excel
                    data1 = [app.x',app.y',app.y1'];
                    xlswrite([app.EditField.Value,'.xlsx'],data1);
                else
                    %存入txt
                    A = [app.x; app.y];
                    fileID = fopen([app.EditField.Value,'.txt'],'w');
                    fprintf(fileID,'%6s %12s\n','t','data');
                    fprintf(fileID,'%6.2f %12.8f\n',A);
                    fclose(fileID);
                    %存入Excel
                    data1 = [app.x',app.y'];
                    xlswrite([app.EditField.Value,'.xlsx'],data1);
                end
            else
                app.SaveButton.BackgroundColor = [0.94,0.94,0.94];
            end
        end

        % Value changed function: stFilterConstant
        function stFilterConstantValueChanged(app, event)
            value = app.stFilterConstant.Value;
            num = str2num(value);
            hex_value = dec2hex(num);
            D = sscanf(hex_value, '%x');
           fwrite(app.s, D, 'uint8');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ActualDataMeasureToolLabel = uilabel(app.UIFigure);
            app.ActualDataMeasureToolLabel.Text = 'Actual Data Measure Tool';
            app.ScopeZonePanel = uipanel(app.UIFigure);
            app.ScopeZonePanel.Title = 'ScopeZone';
            app.Sharp = uiaxes(app.ScopeZonePanel);
            title(app.Sharp, 'Sharp Feedback Voltage Signal')
            xlabel(app.Sharp, 'Time/s')
            ylabel(app.Sharp, 'Value')
            app.SharOn_Off = uibutton(app.ScopeZonePanel, 'state');
            app.SharOn_Off.ValueChangedFcn = createCallbackFcn(app, @SharOn_OffValueChanged, true);
            app.SharOn_Off.Text = 'OFF';
            app.Sharp_Comp = uibutton(app.ScopeZonePanel, 'state');
            app.Sharp_Comp.ValueChangedFcn = createCallbackFcn(app, @Sharp_CompValueChanged, true);
            app.Sharp_Comp.Text = 'Compare';
            app.RawValueEditFieldLabel = uilabel(app.ScopeZonePanel);
            app.RawValueEditFieldLabel.Text = 'RawValue';
            app.RawValueEditField = uieditfield(app.ScopeZonePanel, 'numeric');
            app.RawValueEditField.Editable = 'off';
            app.RawValueEditField_2 = uieditfield(app.ScopeZonePanel, 'numeric');
            app.RawValueEditField_2.Editable = 'off';
            app.RawValueEditFieldLabel_2 = uilabel(app.ScopeZonePanel);
            app.RawValueEditFieldLabel_2.Text = 'FilterValue';
            app.ComandZonePanel = uipanel(app.UIFigure);
            app.ComandZonePanel.Title = 'ComandZone';
            app.StartMeasure = uibutton(app.ComandZonePanel, 'push');
            app.StartMeasure.ButtonPushedFcn = createCallbackFcn(app, @StartMeasureButtonPushed, true);
            app.StartMeasure.Text = 'StartMeasure';
            app.ResetButton = uibutton(app.ComandZonePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.COM = uidropdown(app.ComandZonePanel);
            app.COM.Items = {};
            app.COM.Value = {};
            app.SampSel = uidropdown(app.ComandZonePanel);
            app.SampSel.Items = {};
            app.SampSel.Value = {};
            app.basetime = uieditfield(app.ComandZonePanel, 'numeric');
            app.basetime.Value = 0.2;
            app.SaveButton = uibutton(app.ComandZonePanel, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.EditField = uieditfield(app.ComandZonePanel, 'text');
            app.EditField.Value = 'data';
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.stFilterConstantEditFieldLabel = uilabel(app.CalibrationPanel);
            app.stFilterConstantEditFieldLabel.Text = '1st Filter Constant';
            app.stFilterConstant = uieditfield(app.CalibrationPanel, 'text');
            app.stFilterConstant.ValueChangedFcn = createCallbackFcn(app, @stFilterConstantValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MeasureTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end